classdef SpatialSparrow_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AdjustmentTab                   matlab.ui.container.Tab
        SpoutadjustmentPanel            matlab.ui.container.Panel
        SpoutsetinnerButton             matlab.ui.control.Button
        SpoutsetouterButton             matlab.ui.control.Button
        SpoutMirrorspouts               matlab.ui.control.CheckBox
        LeftspoutKnobLabel              matlab.ui.control.Label
        SpoutLeftKnob                   matlab.ui.control.Knob
        RightspoutKnobLabel             matlab.ui.control.Label
        SpoutRightKnob                  matlab.ui.control.Knob
        KnobmaxEditFieldLabel           matlab.ui.control.Label
        KnobmaxEditField                matlab.ui.control.NumericEditField
        spouttozero                     matlab.ui.control.Button
        MovementdurationmsEditFieldLabel  matlab.ui.control.Label
        spoutspeedEditField             matlab.ui.control.NumericEditField
        spouttoouter                    matlab.ui.control.Button
        spouttoinner                    matlab.ui.control.Button
        SensorthresholdsPanel           matlab.ui.container.Panel
        TouchthresholdsEditFieldLabel   matlab.ui.control.Label
        TouchthresholdsEditField        matlab.ui.control.NumericEditField
        LeftspoutSliderLabel            matlab.ui.control.Label
        LeftspoutSlider                 matlab.ui.control.Slider
        RightspoutSliderLabel           matlab.ui.control.Label
        RightspoutSlider                matlab.ui.control.Slider
        LefthandleSliderLabel           matlab.ui.control.Label
        LefthandleSlider                matlab.ui.control.Slider
        UpdateThreshButton              matlab.ui.control.Button
        RighthandleSliderLabel          matlab.ui.control.Label
        RighthandleSlider               matlab.ui.control.Slider
        HandlesPanel                    matlab.ui.container.Panel
        HandlepositionKnobLabel         matlab.ui.control.Label
        HandlepositionKnob              matlab.ui.control.Knob
        HandlezeroButton                matlab.ui.control.Button
        MovementdurationmsEditField_2Label  matlab.ui.control.Label
        handlespeed                     matlab.ui.control.NumericEditField
        HandlemaxEditFieldLabel         matlab.ui.control.Label
        HandlemaxEditField              matlab.ui.control.NumericEditField
        handlesetinner                  matlab.ui.control.Button
        handlesetouter                  matlab.ui.control.Button
        handletoouter                   matlab.ui.control.Button
        handletoinner                   matlab.ui.control.Button
        TaskTab                         matlab.ui.container.Tab
        TasksettingsPanel               matlab.ui.container.Panel
        TargetrateEditFieldLabel        matlab.ui.control.Label
        TargetrateEditField             matlab.ui.control.NumericEditField
        distractorvaluesEditFieldLabel  matlab.ui.control.Label
        distractorvaluesEditField       matlab.ui.control.EditField
        ModalityDropDownLabel           matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        pRightEditFieldLabel            matlab.ui.control.Label
        pRightEditField                 matlab.ui.control.NumericEditField
        pDistEditFieldLabel             matlab.ui.control.Label
        pDistEditField                  matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        pAudioEditFieldLabel            matlab.ui.control.Label
        pAudioEditField                 matlab.ui.control.NumericEditField
        pVisualEditFieldLabel           matlab.ui.control.Label
        pVisualEditField                matlab.ui.control.NumericEditField
        pSomatosensoryEditFieldLabel    matlab.ui.control.Label
        pSomatosensoryEditField         matlab.ui.control.NumericEditField
        TrainingPanel                   matlab.ui.container.Panel
        TrainingmodeCheckBox            matlab.ui.control.CheckBox
        autorewardCheckBox              matlab.ui.control.CheckBox
        fractionselfperformedPanel      matlab.ui.container.Panel
        fractionmixedEditFieldLabel     matlab.ui.control.Label
        fractionmixedEditField          matlab.ui.control.NumericEditField
        fractionaudioEditFieldLabel     matlab.ui.control.Label
        fractionaudioEditField          matlab.ui.control.NumericEditField
        fractionvisualEditFieldLabel    matlab.ui.control.Label
        fractionvisualEditField         matlab.ui.control.NumericEditField
        fractiontactileEditFieldLabel   matlab.ui.control.Label
        fractiontactileEditField        matlab.ui.control.NumericEditField
        trial_ax                        matlab.ui.control.UIAxes
        trial_performance_ax            matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        optogeneticsPanel               matlab.ui.container.Panel
        fractiontrialsEditFieldLabel    matlab.ui.control.Label
        optofractiontrialsEditField     matlab.ui.control.NumericEditField
        durationEditFieldLabel          matlab.ui.control.Label
        optpdurationEditField           matlab.ui.control.NumericEditField
        periodDropDownLabel             matlab.ui.control.Label
        optoperiodDropDown              matlab.ui.control.DropDown
        Subjectname                     matlab.ui.control.Label
        PauseSwitch                     matlab.ui.control.Switch
        SavesettingsButton              matlab.ui.control.Button
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEdit                      matlab.ui.control.EditField
        Water                           matlab.ui.control.Label
        Trial                           matlab.ui.control.Label
    end

    
    methods (Access = public)
        function results = getSettingsFromBpod(app)
            global BpodSystem
            S = BpodSystem.ProtocolSettings;
            set(app.Subjectname,'Text',S.SubjectName)
            if isfield(BpodSystem.Data,'animalWeight')
                set(app.Subjectname,'Value',BpodSystem.Data.animalWeight)
            end
            %spouts and handles
            set(app.spoutspeedEditField,'Value',BpodSystem.ProtocolSettings.SpoutSpeed)
            set(app.handlespeed,'Value',BpodSystem.ProtocolSettings.LeverSpeed)
            % touch thresh
            set(app.TouchthresholdsEditField,'Value',BpodSystem.ProtocolSettings.TouchThresh)
            %training mode
            set(app.TrainingmodeCheckBox,'Value',BpodSystem.ProtocolSettings.TrainingMode)
            set(app.autorewardCheckBox,'Value',BpodSystem.ProtocolSettings.AutoReward)
            set(app.fractionmixedEditField,'Value', BpodSystem.ProtocolSettings.fractionTrainingMixed)
            set(app.fractionaudioEditField, 'Value', BpodSystem.ProtocolSettings.fractionTrainingAudio)
            set(app.fractionvisualEditField, 'Value',BpodSystem.ProtocolSettings.fractionTrainingVision)
            set(app.fractiontactileEditField, 'Value',BpodSystem.ProtocolSettings.fractionTrainingPiezo)
            % modalit/task
            set(app.ModalityDropDown,'Value',BpodSystem.ProtocolSettings.RewardedModality)
            set(app.pRightEditField,'Value',BpodSystem.ProtocolSettings.ProbRight)
            set(app.pAudioEditField,'Value',BpodSystem.ProtocolSettings.ProbAudio)
            set(app.pVisualEditField,'Value',BpodSystem.ProtocolSettings.ProbVision)
            set(app.pSomatosensoryEditField,'Value',BpodSystem.ProtocolSettings.ProbPiezo)
            set(app.pDistEditField,'Value',BpodSystem.ProtocolSettings.DistProb)
            set(app.TargetrateEditField,'Value',BpodSystem.ProtocolSettings.TargRate)
            set(app.distractorvaluesEditField,'Value',num2str(BpodSystem.ProtocolSettings.DistFractions))
        end
    end 
    
    methods (Access = private)
        
        function results = moveSpouts(app,l,r)
            set(app.SpoutLeftKnob,'Value',l); %update slider
            set(app.SpoutRightKnob,'Value',r);  %update slider
            ls = num2str(l);rs = num2str(r);
            teensyWrite([71 length(ls)  ls length(rs)  rs]); %move the spouts
        end
        
        function results = sendTeensyStepperTrialInfo(app)
            % this might be simplified in the teeny side..
            global BpodSystem
            LeftIn = round(BpodSystem.ProtocolSettings.lInnerLim,2) ; %left inner position
            RightIn = round(BpodSystem.ProtocolSettings.rInnerLim,2); %right inner position
            LeftOut = LeftIn + BpodSystem.ProtocolSettings.spoutOffset; %left outer position
            RightOut = RightIn + BpodSystem.ProtocolSettings.spoutOffset; %right outer position
            
            % convert to strings and combine as teensy output
            LeftIn = num2str(LeftIn); RightIn = num2str(RightIn);
            LeftOut = num2str(LeftOut); RightOut = num2str(RightOut);
            LeverIn = num2str(BpodSystem.ProtocolSettings.LeverIn);
            LeverOut = num2str(BpodSystem.ProtocolSettings.LeverOut);
            
            % send trial information to teensy - this is needed to compute the
            % leverspeed from outer to inner position
            cVal = [length(LeftIn) length(RightIn) length(LeftOut) length(RightOut) length(LeverIn) length(LeverOut) ...
                LeftIn RightIn LeftOut RightOut LeverIn LeverOut];
            teensyWrite([70 uint8(cVal)]); % send spout/lever information to teensy at trial start
            
        end
        
        function results = moveHandles(app,v)
            set(app.HandlepositionKnob,'Value',v); %update slider
            v = num2str(v);
            teensyWrite([72 length(v)  v]); %move the spouts
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HandlemaxEditField
        function HandlemaxEditFieldValueChanged(app, event)
            value = app.HandlemaxEditField.Value;
            app.HandlepositionKnob.Limits = [0,value];
        end

        % Value changed function: TrainingmodeCheckBox
        function TrainingmodeCheckBoxValueChanged(app, event)
            value = app.TrainingmodeCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TrainingMode = value;
            if ~value
                set(app.autorewardCheckBox,'Enable','off')
                BpodSystem.ProtocolSettings.AutoReward = value;
            else
                set(app.autorewardCheckBox,'Enable','on')
            end
        end

        % Value changed function: PauseSwitch
        function PauseSwitchValueChanged(app, event)
            value = app.PauseSwitch.Value;
            global BpodSystem
            BpodSystem.Status.SpatialSparrowPause = value;
        end

        % Button pushed function: spouttozero
        function spouttozeroButtonPushed(app, event)
            moveSpouts(app,0,0)
        end

        % Button pushed function: spouttoouter
        function spouttoouterButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lOuterLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rOuterLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout);
        end

        % Button pushed function: spouttoinner
        function spouttoinnerButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lInnerLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rInnerLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout)
        end

        % Button pushed function: SpoutsetinnerButton
        function SpoutsetinnerButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lInnerLim = app.SpoutLeftKnob.Value; %get left inner limit
            BpodSystem.ProtocolSettings.rInnerLim = app.SpoutRightKnob.Value; %get right inner limit
            disp('Set inner positions')
        end

        % Button pushed function: SpoutsetouterButton
        function SpoutsetouterButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lOuterLim = app.SpoutLeftKnob.Value; %get left inner limit
            BpodSystem.ProtocolSettings.rOuterLim = app.SpoutRightKnob.Value; %get right inner limit
            disp('Set outer positions')
        end

        % Value changed function: KnobmaxEditField
        function KnobmaxEditFieldValueChanged(app, event)
            value = app.KnobmaxEditField.Value;
            set(app.SpoutLeftKnob,'Limits',[0,value]);
            set(app.SpoutRightKnob,'Limits',[0,value]);
        end

        % Value changed function: spoutspeedEditField
        function spoutspeedEditFieldValueChanged(app, event)
            value = app.spoutspeedEditField.Value;
            global BpodSystem
            app.sendTeensyStepperTrialInfo();
            BpodSystem.ProtocolSettings.SpoutSpeed = value; %update SpoutSpeed
            teensyWrite([73 length(num2str(value)) value]); %set spout speed
        end

        % Value changed function: SpoutLeftKnob
        function SpoutLeftKnobValueChanged(app, event)
            value = app.SpoutLeftKnob.Value;
            if app.SpoutMirrorspouts.Value
                set(app.SpoutRightKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutLeftKnob
        function SpoutLeftKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            %drawnow;
            if app.SpoutMirrorspouts.Value == false
                valuer = app.SpoutRightKnob.Value;
            end
            app.moveSpouts(valuel,valuer);
        end

        % Value changing function: SpoutRightKnob
        function SpoutRightKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            if app.SpoutMirrorspouts.Value == false
                valuel = app.SpoutLeftKnob.Value;
            end
            app.moveSpouts(valuel,valuer);
            
        end

        % Value changing function: HandlepositionKnob
        function HandlepositionKnobValueChanging(app, event)
            changingValue = ceil(event.Value);
            app.moveHandles(changingValue);
        end

        % Button pushed function: HandlezeroButton
        function HandlezeroButtonPushed(app, event)
            app.moveHandles(0);
        end

        % Button pushed function: handletoouter
        function handletoouterButtonPushed(app, event)
            global BpodSystem
            app.moveHandles(BpodSystem.ProtocolSettings.LeverOut);
            
        end

        % Button pushed function: handletoinner
        function handletoinnerButtonPushed(app, event)
            global BpodSystem
            app.moveHandles(BpodSystem.ProtocolSettings.LeverIn);
        end

        % Button pushed function: handlesetinner
        function handlesetinnerButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.LeverIn = app.HandlepositionKnob.Value;
        end

        % Button pushed function: handlesetouter
        function handlesetouterButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.LeverOut = app.HandlepositionKnob.Value;
        end

        % Value changed function: handlespeed
        function handlespeedValueChanged(app, event)
            value = app.handlespeed.Value;
            BpodSystem.ProtocolSettings.LeverSpeed = value; %update LeverSpeed
            app.sendTeensyStepperTrialInfo();
            s = num2str(ceil(value));
            teensyWrite([74 length(s) s]); %set lever speed
            
            
        end

        % Value changed function: TouchthresholdsEditField
        function TouchthresholdsEditFieldValueChanged(app, event)
            value = ceil(app.TouchthresholdsEditField.Value);
            if value < 1
                value = 1;
                set(app.TouchthresholdsEditField,'Value',1);
            end
            global BpodSystem
            BpodSystem.ProtocolSettings.TouchThresh = value;
            cval = num2str(value);
            teensyWrite([75 length(cval) cval]);
        end

        % Button pushed function: UpdateThreshButton
        function UpdateThreshButtonPushed(app, event)
            global BpodSystem
            value = BpodSystem.ProtocolSettings.TouchThresh;
            cval = num2str(value);
            teensyWrite([75 length(cval) cval]);
        end

        % Value changed function: LeftspoutSlider
        function LeftspoutSliderValueChanged(app, event)
            set(app.LeftspoutSlider,'Value',0);
            % bring back to zero
        end

        % Value changing function: LeftspoutSlider
        function LeftspoutSliderValueChanging(app, event)
            changingValue = event.Value;
            for i = 1:abs(changingValue)
                if changingValue < 0
                    teensyWrite(81);
                else
                    teensyWrite(80);
                end
            end
        end

        % Value changing function: RightspoutSlider
        function RightspoutSliderValueChanging(app, event)
            
            changingValue = event.Value;
            for i = 1:abs(changingValue)
                if changingValue < 0
                    teensyWrite(82);
                else
                    teensyWrite(83);
                end
            end
        end

        % Value changed function: RightspoutSlider
        function RightspoutSliderValueChanged(app, event)
            value = app.RightspoutSlider.Value;
            set(app.RightspoutSlider,'Value',0);
            
        end

        % Value changed function: RighthandleSlider
        function RighthandleSliderValueChanged(app, event)
            value = app.RighthandleSlider.Value;
            set(app.RighthandleSlider,'Value',0);
        end

        % Value changed function: LefthandleSlider
        function LefthandleSliderValueChanged(app, event)
            value = app.LefthandleSlider.Value;
            set(app.LefthandleSlider,'Value',0);
            
        end

        % Value changing function: RighthandleSlider
        function RighthandleSliderValueChanging(app, event)
            changingValue = event.Value;
            for i = 1:abs(changingValue)
                if changingValue < 0
                    teensyWrite(86);
                else
                    teensyWrite(87);
                end
            end
        end

        % Value changing function: LefthandleSlider
        function LefthandleSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue < 0
                for i = 1:abs(changingValue)
                    teensyWrite(84);
                end
            else
                for i = 1:abs(changingValue)
                    teensyWrite(85);
                end
            end
        end

        % Value changed function: autorewardCheckBox
        function autorewardCheckBoxValueChanged(app, event)
            value = app.autorewardCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.AutoReward = value;
        end

        % Value changed function: fractionmixedEditField
        function fractionmixedEditFieldValueChanged(app, event)
            value = app.fractionmixedEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingMixed = value;
        end

        % Value changed function: fractionvisualEditField
        function fractionvisualEditFieldValueChanged(app, event)
            value = app.fractionvisualEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingVision = value;
        end

        % Value changed function: fractionaudioEditField
        function fractionaudioEditFieldValueChanged(app, event)
            value = app.fractionaudioEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingAudio = value;
        end

        % Value changed function: fractiontactileEditField
        function fractiontactileEditFieldValueChanged(app, event)
            value = app.fractiontactileEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingPiezo = value;
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            value = app.ModalityDropDown.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.RewardedModality = value;
        end

        % Value changed function: pRightEditField
        function pRightEditFieldValueChanged(app, event)
            value = app.pRightEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbRight = value;
        end

        % Value changed function: TargetrateEditField
        function TargetrateEditFieldValueChanged(app, event)
            value = app.TargetrateEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TargRate = value;
        end

        % Value changed function: pDistEditField
        function pDistEditFieldValueChanged(app, event)
            value = app.pDistEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.DistProb = value;
        end

        % Value changed function: pAudioEditField
        function pAudioEditFieldValueChanged(app, event)
            value = app.pAudioEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbAudio = value;
        end

        % Value changed function: pVisualEditField
        function pVisualEditFieldValueChanged(app, event)
            value = app.pVisualEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbVision = value;
        end

        % Value changed function: pSomatosensoryEditField
        function pSomatosensoryEditFieldValueChanged(app, event)
            value = app.pSomatosensoryEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbPiezo = value;
        end

        % Value changed function: distractorvaluesEditField
        function distractorvaluesEditFieldValueChanged(app, event)
            value = app.distractorvaluesEditField.Value;
            p = str2num(value)
            global BpodSystem
            BpodSystem.ProtocolSettings.DistFractions = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdjustmentTab = uitab(app.TabGroup);
            app.AdjustmentTab.Title = 'Adjustment';
            app.SpoutadjustmentPanel = uipanel(app.AdjustmentTab);
            app.SpoutadjustmentPanel.Title = 'Spout adjustment';
            app.SpoutsetinnerButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetinnerButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetinnerButtonPushed, true);
            app.SpoutsetinnerButton.Text = 'set';
            app.SpoutsetouterButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetouterButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetouterButtonPushed, true);
            app.SpoutsetouterButton.Text = 'set';
            app.SpoutMirrorspouts = uicheckbox(app.SpoutadjustmentPanel);
            app.SpoutMirrorspouts.Text = 'Mirror movement';
            app.SpoutMirrorspouts.Value = true;
            app.LeftspoutKnobLabel = uilabel(app.SpoutadjustmentPanel);
            app.LeftspoutKnobLabel.Text = 'Left spout';
            app.SpoutLeftKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutLeftKnob.Limits = [0 60];
            app.SpoutLeftKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanged, true);
            app.SpoutLeftKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanging, true);
            app.RightspoutKnobLabel = uilabel(app.SpoutadjustmentPanel);
            app.RightspoutKnobLabel.Text = 'Right spout';
            app.SpoutRightKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutRightKnob.Limits = [0 60];
            app.SpoutRightKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutRightKnobValueChanging, true);
            app.KnobmaxEditFieldLabel = uilabel(app.SpoutadjustmentPanel);
            app.KnobmaxEditFieldLabel.Text = 'Knob max';
            app.KnobmaxEditField = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.KnobmaxEditField.ValueChangedFcn = createCallbackFcn(app, @KnobmaxEditFieldValueChanged, true);
            app.KnobmaxEditField.Value = 60;
            app.spouttozero = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttozero.ButtonPushedFcn = createCallbackFcn(app, @spouttozeroButtonPushed, true);
            app.spouttozero.Text = 'zero';
            app.MovementdurationmsEditFieldLabel = uilabel(app.SpoutadjustmentPanel);
            app.MovementdurationmsEditFieldLabel.Text = 'Movement duration (ms)';
            app.spoutspeedEditField = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.spoutspeedEditField.ValueChangedFcn = createCallbackFcn(app, @spoutspeedEditFieldValueChanged, true);
            app.spoutspeedEditField.Value = 25;
            app.spouttoouter = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoouter.ButtonPushedFcn = createCallbackFcn(app, @spouttoouterButtonPushed, true);
            app.spouttoouter.Text = 'outer';
            app.spouttoinner = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoinner.ButtonPushedFcn = createCallbackFcn(app, @spouttoinnerButtonPushed, true);
            app.spouttoinner.Text = 'inner';
            app.SensorthresholdsPanel = uipanel(app.AdjustmentTab);
            app.SensorthresholdsPanel.Title = 'Sensor thresholds';
            app.TouchthresholdsEditFieldLabel = uilabel(app.SensorthresholdsPanel);
            app.TouchthresholdsEditFieldLabel.Text = 'Touch thresholds';
            app.TouchthresholdsEditField = uieditfield(app.SensorthresholdsPanel, 'numeric');
            app.TouchthresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @TouchthresholdsEditFieldValueChanged, true);
            app.TouchthresholdsEditField.Value = 3;
            app.LeftspoutSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.LeftspoutSliderLabel.Text = 'Left spout';
            app.LeftspoutSlider = uislider(app.SensorthresholdsPanel);
            app.LeftspoutSlider.Limits = [-10 10];
            app.LeftspoutSlider.ValueChangedFcn = createCallbackFcn(app, @LeftspoutSliderValueChanged, true);
            app.LeftspoutSlider.ValueChangingFcn = createCallbackFcn(app, @LeftspoutSliderValueChanging, true);
            app.RightspoutSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.RightspoutSliderLabel.Text = 'Right spout';
            app.RightspoutSlider = uislider(app.SensorthresholdsPanel);
            app.RightspoutSlider.Limits = [-10 10];
            app.RightspoutSlider.ValueChangedFcn = createCallbackFcn(app, @RightspoutSliderValueChanged, true);
            app.RightspoutSlider.ValueChangingFcn = createCallbackFcn(app, @RightspoutSliderValueChanging, true);
            app.LefthandleSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.LefthandleSliderLabel.Text = 'Left handle';
            app.LefthandleSlider = uislider(app.SensorthresholdsPanel);
            app.LefthandleSlider.Limits = [-10 10];
            app.LefthandleSlider.ValueChangedFcn = createCallbackFcn(app, @LefthandleSliderValueChanged, true);
            app.LefthandleSlider.ValueChangingFcn = createCallbackFcn(app, @LefthandleSliderValueChanging, true);
            app.UpdateThreshButton = uibutton(app.SensorthresholdsPanel, 'push');
            app.UpdateThreshButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThreshButtonPushed, true);
            app.UpdateThreshButton.Text = 'Update';
            app.RighthandleSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.RighthandleSliderLabel.Text = 'Right handle';
            app.RighthandleSlider = uislider(app.SensorthresholdsPanel);
            app.RighthandleSlider.Limits = [-10 10];
            app.RighthandleSlider.ValueChangedFcn = createCallbackFcn(app, @RighthandleSliderValueChanged, true);
            app.RighthandleSlider.ValueChangingFcn = createCallbackFcn(app, @RighthandleSliderValueChanging, true);
            app.HandlesPanel = uipanel(app.AdjustmentTab);
            app.HandlesPanel.Title = 'Handles';
            app.HandlepositionKnobLabel = uilabel(app.HandlesPanel);
            app.HandlepositionKnobLabel.Text = 'Handle position';
            app.HandlepositionKnob = uiknob(app.HandlesPanel, 'continuous');
            app.HandlepositionKnob.Limits = [0 150];
            app.HandlepositionKnob.ValueChangingFcn = createCallbackFcn(app, @HandlepositionKnobValueChanging, true);
            app.HandlezeroButton = uibutton(app.HandlesPanel, 'push');
            app.HandlezeroButton.ButtonPushedFcn = createCallbackFcn(app, @HandlezeroButtonPushed, true);
            app.HandlezeroButton.Text = 'zero';
            app.MovementdurationmsEditField_2Label = uilabel(app.HandlesPanel);
            app.MovementdurationmsEditField_2Label.Text = 'Movement duration (ms)';
            app.handlespeed = uieditfield(app.HandlesPanel, 'numeric');
            app.handlespeed.ValueChangedFcn = createCallbackFcn(app, @handlespeedValueChanged, true);
            app.handlespeed.Value = 100;
            app.HandlemaxEditFieldLabel = uilabel(app.HandlesPanel);
            app.HandlemaxEditFieldLabel.Text = 'Handle max';
            app.HandlemaxEditField = uieditfield(app.HandlesPanel, 'numeric');
            app.HandlemaxEditField.ValueChangedFcn = createCallbackFcn(app, @HandlemaxEditFieldValueChanged, true);
            app.HandlemaxEditField.Value = 150;
            app.handlesetinner = uibutton(app.HandlesPanel, 'push');
            app.handlesetinner.ButtonPushedFcn = createCallbackFcn(app, @handlesetinnerButtonPushed, true);
            app.handlesetinner.Text = 'set';
            app.handlesetouter = uibutton(app.HandlesPanel, 'push');
            app.handlesetouter.ButtonPushedFcn = createCallbackFcn(app, @handlesetouterButtonPushed, true);
            app.handlesetouter.Text = 'set';
            app.handletoouter = uibutton(app.HandlesPanel, 'push');
            app.handletoouter.ButtonPushedFcn = createCallbackFcn(app, @handletoouterButtonPushed, true);
            app.handletoouter.Text = 'outer';
            app.handletoinner = uibutton(app.HandlesPanel, 'push');
            app.handletoinner.ButtonPushedFcn = createCallbackFcn(app, @handletoinnerButtonPushed, true);
            app.handletoinner.Text = 'inner';
            app.TaskTab = uitab(app.TabGroup);
            app.TaskTab.Title = 'Task';
            app.TasksettingsPanel = uipanel(app.TaskTab);
            app.TasksettingsPanel.Title = 'Task settings';
            app.TargetrateEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.TargetrateEditFieldLabel.Text = 'Target rate';
            app.TargetrateEditField = uieditfield(app.TasksettingsPanel, 'numeric');
            app.TargetrateEditField.ValueChangedFcn = createCallbackFcn(app, @TargetrateEditFieldValueChanged, true);
            app.TargetrateEditField.Value = 20;
            app.distractorvaluesEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.distractorvaluesEditFieldLabel.Text = 'distractor  values';
            app.distractorvaluesEditField = uieditfield(app.TasksettingsPanel, 'text');
            app.distractorvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @distractorvaluesEditFieldValueChanged, true);
            app.distractorvaluesEditField.Value = '0';
            app.ModalityDropDownLabel = uilabel(app.TasksettingsPanel);
            app.ModalityDropDownLabel.Text = 'Modality';
            app.ModalityDropDown = uidropdown(app.TasksettingsPanel);
            app.ModalityDropDown.Items = {'Vision', 'Audio', 'AudioVisual', 'Somatosensory', 'SomatoVisual', 'SomatoAudio', 'AllMixed'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = 'Vision';
            app.pRightEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.pRightEditFieldLabel.Text = 'pRight';
            app.pRightEditField = uieditfield(app.TasksettingsPanel, 'numeric');
            app.pRightEditField.ValueChangedFcn = createCallbackFcn(app, @pRightEditFieldValueChanged, true);
            app.pRightEditField.Value = 0.5;
            app.pDistEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.pDistEditFieldLabel.Text = 'pDist';
            app.pDistEditField = uieditfield(app.TasksettingsPanel, 'numeric');
            app.pDistEditField.ValueChangedFcn = createCallbackFcn(app, @pDistEditFieldValueChanged, true);
            app.Panel = uipanel(app.TasksettingsPanel);
            app.pAudioEditFieldLabel = uilabel(app.Panel);
            app.pAudioEditFieldLabel.Text = 'pAudio';
            app.pAudioEditField = uieditfield(app.Panel, 'numeric');
            app.pAudioEditField.ValueChangedFcn = createCallbackFcn(app, @pAudioEditFieldValueChanged, true);
            app.pVisualEditFieldLabel = uilabel(app.Panel);
            app.pVisualEditFieldLabel.Text = 'pVisual';
            app.pVisualEditField = uieditfield(app.Panel, 'numeric');
            app.pVisualEditField.ValueChangedFcn = createCallbackFcn(app, @pVisualEditFieldValueChanged, true);
            app.pSomatosensoryEditFieldLabel = uilabel(app.Panel);
            app.pSomatosensoryEditFieldLabel.Text = 'pSomatosensory';
            app.pSomatosensoryEditField = uieditfield(app.Panel, 'numeric');
            app.pSomatosensoryEditField.ValueChangedFcn = createCallbackFcn(app, @pSomatosensoryEditFieldValueChanged, true);
            app.TrainingPanel = uipanel(app.TaskTab);
            app.TrainingPanel.Title = 'Training';
            app.TrainingmodeCheckBox = uicheckbox(app.TrainingPanel);
            app.TrainingmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainingmodeCheckBoxValueChanged, true);
            app.TrainingmodeCheckBox.Text = 'Training mode';
            app.autorewardCheckBox = uicheckbox(app.TrainingPanel);
            app.autorewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @autorewardCheckBoxValueChanged, true);
            app.autorewardCheckBox.Text = 'auto-reward';
            app.fractionselfperformedPanel = uipanel(app.TrainingPanel);
            app.fractionselfperformedPanel.Title = 'fraction self-performed';
            app.fractionmixedEditFieldLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionmixedEditFieldLabel.Text = 'fraction mixed ';
            app.fractionmixedEditField = uieditfield(app.fractionselfperformedPanel, 'numeric');
            app.fractionmixedEditField.ValueChangedFcn = createCallbackFcn(app, @fractionmixedEditFieldValueChanged, true);
            app.fractionaudioEditFieldLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionaudioEditFieldLabel.Text = 'fraction audio';
            app.fractionaudioEditField = uieditfield(app.fractionselfperformedPanel, 'numeric');
            app.fractionaudioEditField.ValueChangedFcn = createCallbackFcn(app, @fractionaudioEditFieldValueChanged, true);
            app.fractionvisualEditFieldLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionvisualEditFieldLabel.Text = 'fraction visual';
            app.fractionvisualEditField = uieditfield(app.fractionselfperformedPanel, 'numeric');
            app.fractionvisualEditField.ValueChangedFcn = createCallbackFcn(app, @fractionvisualEditFieldValueChanged, true);
            app.fractiontactileEditFieldLabel = uilabel(app.fractionselfperformedPanel);
            app.fractiontactileEditFieldLabel.Text = 'fraction tactile';
            app.fractiontactileEditField = uieditfield(app.fractionselfperformedPanel, 'numeric');
            app.fractiontactileEditField.ValueChangedFcn = createCallbackFcn(app, @fractiontactileEditFieldValueChanged, true);
            app.trial_ax = uiaxes(app.TaskTab);
            app.trial_ax.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.trial_ax.XTickLabel = '';
            app.trial_ax.YTick = [0 1];
            app.trial_ax.YTickLabel = {'L'; 'R'};
            app.trial_performance_ax = uiaxes(app.TaskTab);
            xlabel(app.trial_performance_ax, 'trial number')
            ylabel(app.trial_performance_ax, 'Performance')
            app.UIAxes = uiaxes(app.TaskTab);
            title(app.UIAxes, 'Stimuli')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.TaskTab);
            title(app.UIAxes_2, 'Psychometrics')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.optogeneticsPanel = uipanel(app.SettingsTab);
            app.optogeneticsPanel.Title = 'optogenetics';
            app.fractiontrialsEditFieldLabel = uilabel(app.optogeneticsPanel);
            app.fractiontrialsEditFieldLabel.Text = 'fraction trials';
            app.optofractiontrialsEditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.durationEditFieldLabel = uilabel(app.optogeneticsPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.optpdurationEditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.periodDropDownLabel = uilabel(app.optogeneticsPanel);
            app.periodDropDownLabel.Text = 'period';
            app.optoperiodDropDown = uidropdown(app.optogeneticsPanel);
            app.optoperiodDropDown.Items = {'Stimulus', 'Delay', 'Stimulus/Delay'};
            app.optoperiodDropDown.Value = 'Stimulus';
            app.Subjectname = uilabel(app.UIFigure);
            app.Subjectname.Text = 'Subject name';
            app.PauseSwitch = uiswitch(app.UIFigure, 'slider');
            app.PauseSwitch.Items = {'pause', 'run'};
            app.PauseSwitch.ValueChangedFcn = createCallbackFcn(app, @PauseSwitchValueChanged, true);
            app.PauseSwitch.Value = 'pause';
            app.SavesettingsButton = uibutton(app.UIFigure, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEdit = uieditfield(app.UIFigure, 'text');
            app.Water = uilabel(app.UIFigure);
            app.Water.Text = 'Trial N/N';
            app.Trial = uilabel(app.UIFigure);
            app.Trial.Text = 'Water Xul';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpatialSparrow_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end